classdef devphysiodaq_popup < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    devicechannelsUIFigure  matlab.ui.Figure
    settingsPanel           matlab.ui.container.Panel
    channelDropDown         matlab.ui.control.DropDown
    channelDropDownLabel    matlab.ui.control.Label
    offsetEdit              matlab.ui.control.NumericEditField
    offsetEditFieldLabel    matlab.ui.control.Label
    gainEdit                matlab.ui.control.NumericEditField
    gainEditFieldLabel      matlab.ui.control.Label
    CancelButton            matlab.ui.control.Button
    OKButton                matlab.ui.control.Button
  end

  
  properties (Access = private)
    m_caller;             % calling app
    m_analogStruct;       % structure with all settings of the dialog
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, device, position)
      % Note: position should be set in the future
      
      app.devicechannelsUIFigure.Visible = 'off';

      appPosition = app.devicechannelsUIFigure.Position;
      appPosition(1) = position(1);
      appPosition(2) = position(2);
      app.devicechannelsUIFigure.Position = appPosition;

      app.m_caller = caller;
      app.m_analogStruct = device.getAnalogChannels();

      % fill the dropdown channel menu

      nrAnalogChan = length(app.m_analogStruct);
      nameItem = cell(nrAnalogChan,1);
      itemNr = cell(nrAnalogChan,1);
      for i = 1:nrAnalogChan
        nameItem{i} = app.m_analogStruct(i).name;
        itemNr{i} = num2str(i);
      end
      app.channelDropDown.Items = nameItem;
      app.channelDropDown.ItemsData = itemNr;

      % and we set the first channel

      app.channelDropDown.Value = num2str(1);
      app.offsetEdit.Value = app.m_analogStruct(1).offset;
      app.gainEdit.Value = app.m_analogStruct(1).gain;
      
      app.devicechannelsUIFigure.Visible = 'on';

    end

    % Value changed function: offsetEdit
    function offsetEditValueChanged(app, event)

      % get the correct channel and store the value 

      channelNr = str2double(app.channelDropDown.Value);
      app.m_analogStruct(channelNr).offset = app.offsetEdit.Value;
      
    end

    % Value changed function: gainEdit
    function gainEditValueChanged(app, event)
      
      % get the correct channel and store the value 

      channelNr = str2double(app.channelDropDown.Value);
      app.m_analogStruct(channelNr).gain = app.gainEdit.Value;
      
    end

    % Value changed function: channelDropDown
    function channelDropDownValueChanged(app, event)

      % read the channel number and update gain and offset

      channelNr = str2double(app.channelDropDown.Value);
      app.gainEdit.Value = app.m_analogStruct(channelNr).gain;
      app.offsetEdit.Value = app.m_analogStruct(channelNr).offset;
      
    end

    % Button pushed function: CancelButton
    function CancelButtonPushed(app, event)
      
      % nothing is changed, just delete the app

      app.delete;
      
    end

    % Button pushed function: OKButton
    function OKButtonPushed(app, event)
      
      % store the current structure to the member of the caller before deleting it,
      % configure the setup again and delete the popup

      device = app.m_caller.getDevice();
      device = device.setAnalogChannels(app.m_analogStruct);
      app.m_caller.setDevice(device);

      app.m_caller.statusBar = app.m_caller.statusBar.setText("device settings saved ...",3.0);
      app.delete
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.devicechannelsUIFigure = uifigure('Visible', 'off');
      app.devicechannelsUIFigure.Name = 'device channels';
      app.OKButton = uibutton(app.devicechannelsUIFigure, 'push');
      app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
      app.OKButton.Text = 'OK';
      app.CancelButton = uibutton(app.devicechannelsUIFigure, 'push');
      app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
      app.CancelButton.Text = 'Cancel';
      app.settingsPanel = uipanel(app.devicechannelsUIFigure);
      app.settingsPanel.Title = 'settings';
      app.gainEditFieldLabel = uilabel(app.settingsPanel);
      app.gainEditFieldLabel.Text = 'gain';
      app.gainEdit = uieditfield(app.settingsPanel, 'numeric');
      app.gainEdit.ValueChangedFcn = createCallbackFcn(app, @gainEditValueChanged, true);
      app.offsetEditFieldLabel = uilabel(app.settingsPanel);
      app.offsetEditFieldLabel.Text = 'offset';
      app.offsetEdit = uieditfield(app.settingsPanel, 'numeric');
      app.offsetEdit.ValueChangedFcn = createCallbackFcn(app, @offsetEditValueChanged, true);
      app.channelDropDownLabel = uilabel(app.settingsPanel);
      app.channelDropDownLabel.Text = 'channel';
      app.channelDropDown = uidropdown(app.settingsPanel);
      app.channelDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
      app.channelDropDown.ValueChangedFcn = createCallbackFcn(app, @channelDropDownValueChanged, true);
      app.channelDropDown.Value = '';
      % Show the figure after all components are created
      app.devicechannelsUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = devphysiodaq_popup(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.devicechannelsUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.devicechannelsUIFigure)
    end
  end
end