classdef physiomon < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                matlab.ui.Figure
    FileMenu                matlab.ui.container.Menu
    StartMenu               matlab.ui.container.Menu
    SaveMenu                matlab.ui.container.Menu
    QuitMenu                matlab.ui.container.Menu
    EditMenu                matlab.ui.container.Menu
    onSettingsEdit          matlab.ui.container.Menu
    onDeviceEdit            matlab.ui.container.Menu
    HelpMenu                matlab.ui.container.Menu
    aboutphysiomonMenu      matlab.ui.container.Menu
    Toolbar                 matlab.ui.container.Toolbar
    startToolbar            matlab.ui.container.toolbar.ToggleTool
    saveToolbar             matlab.ui.container.toolbar.ToggleTool
    generalSettingsToolbar  matlab.ui.container.toolbar.PushTool
    deviceSettingsToolbar   matlab.ui.container.toolbar.PushTool
    infoToolbar             matlab.ui.container.toolbar.PushTool
    statusWindow            matlab.ui.control.EditField
    LED_1                   matlab.ui.control.Lamp
    LED_2                   matlab.ui.control.Lamp
    LED_3                   matlab.ui.control.Lamp
  end

  
  properties (Access = private)

    dlgDisplayEdit;         % dialog for editing display settings
    dlgDeviceEdit;          % dialog for device editing

    m_settings              % instance of <physiomon_settings>
    m_device                % instance of measure device calls
    m_channels              % instance of channels class
    m_displays              % instance of <displays>
    m_timer                 % instance of a timer
    
  end
  
  properties (Access = public)
    statusBar               % shows information
  end
  
  methods (Access = public)
    
    %% onConfigure
    %
    %   configures the device, channels and so on depending on the input of the *.INI
    %   files or the popup dialog boxes

    function onConfigure(app)
    
        canvasHandle = app.UIFigure;
        
        app.m_displays = app.m_displays.configure(app.m_settings,canvasHandle);
        app.m_device = app.m_device.configure(app.m_settings);
        app.m_channels = app.m_channels.configure(app.m_settings,app.m_device);
    end
    
    %% setSettings
    %
    %   sets the channel information, display information and event structure, obtained
    %   from the dialog box back in the instance of m_settings

    function app = setSettings(app,channelStruct,displayStruct,eventStruct)
      app.m_settings = app.m_settings.setChannels(channelStruct);
      app.m_settings = app.m_settings.setDisplays(displayStruct);
      app.m_settings = app.m_settings.setEvents(eventStruct);
    end

    %% getDevice
    %
    %   returns the private property <m_device>

    function device = getDevice(app)
      device = app.m_device;
    end
    
    %% setDevice
    %
    %   sets the private property <m_device>
    
    function app = setDevice(app,device)
      app.m_device = device;  
    end
      
    %% onTimer
    % 
    %    function is called at timer periods

    function onTimer(app)
      
      app.statusBar = app.statusBar.update();

      [app.m_device,app.m_channels] = app.m_device.read(app.m_channels);
      [app.m_displays,app.m_channels] = app.m_displays.plot(app.m_channels);
      
    end
    
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, start)
      
      app.UIFigure.Visible = "off";

      % create the statusbar
      
      app.statusBar = statusbar(app.statusWindow,[app.LED_1, app.LED_2, app.LED_3]);
      app.statusBar = app.statusBar.setText("",0);

      % intialise

      app.m_settings = physiomon_settings();
      [app.m_settings,deviceName] = app.m_settings.iniRead();
     
      app.m_device = devPhysioDaq();
      app.m_device = app.m_device.iniRead(deviceName);
      app.m_device = app.m_device.initialise();

      app.m_displays = physiomon_displays();
      app.m_channels = physiomon_channels(5);               % buffers of 5 seconds
      
      % and create the timer, every 100 ms

      app.m_timer = timer('period',0.1,'ExecutionMode','fixedRate','TimerFcn',@(~,~)app.onTimer());
      app.m_timer.start();

      % configure, set ready and make the app visible

      onConfigure(app);
      app.statusBar = app.statusBar.setText("ready ...",5);
      app.UIFigure.Visible = 'on';


    end

    % Callback function: generalSettingsToolbar, onSettingsEdit
    function onSettingsEditSelected(app, event)

      channels = app.m_settings.getChannels();
      displays = app.m_settings.getDisplays();
      events = app.m_settings.getEvents();

      analogChannels = app.m_device.getAnalogChannels;

      position = app.UIFigure.Position;
      pos = floor([(position(1) + 0.1 * position(3)) (position(2) + 0.25 * position(4))]);
      
      app.statusBar = app.statusBar.setText("change general settings ...",3.0);
      app.dlgDisplayEdit = settings_popup(app,channels,displays,events,analogChannels,pos);
    end

    % Callback function: deviceSettingsToolbar, onDeviceEdit
    function onDeviceEditMenuSelected(app, event)

      position = app.UIFigure.Position;
      pos = floor([(position(1) + 0.1 * position(3)) (position(2) + 0.55 * position(4))]);

      % input is the device

      app.statusBar = app.statusBar.setText("change device settings ...",3.0);
      app.dlgDeviceEdit = devphysiodaq_popup(app,app.m_device,pos);
      
    end

    % Menu selected function: aboutphysiomonMenu
    function aboutphysiomonMenuSelected(app, event)


    end

    % Callback function: QuitMenu, UIFigure
    function UIFigureCloseRequest(app, event)
      
      % stop the timer before deleting it

      app.m_timer.stop();
      delete(app.m_timer);

      % and delete the app
      delete(app)
      
    end

    % Callback function: StartMenu, startToolbar
    function onStart(app, event)
      
      started = app.m_device.isStarted();

      if (~started)

        app.startToolbar.Icon = '../stop_9503472.png';
        app.statusBar.setLed(1,'on');
        app.statusBar = app.statusBar.setText("device started ...",5);
        app.StartMenu.Text = "Stop";
        app.m_device = app.m_device.setStartStop(true);
      
        app.onSettingsEdit.Enable = 'off';
        app.onDeviceEdit.Enable = 'off';
        app.generalSettingsToolbar.Enable = 'off';
        app.deviceSettingsToolbar.Enable = 'off';
        app.infoToolbar.Enable = 'off';

      else

        app.startToolbar.Icon = '../play_13794082.png';
        app.statusBar.setLed(1,'off');
        app.statusBar = app.statusBar.setText("device stopped ...",5);
        app.StartMenu.Text = "Start";
        app.m_device = app.m_device.setStartStop(false);
      
        app.onSettingsEdit.Enable = 'on';
        app.onDeviceEdit.Enable = 'on';
        app.generalSettingsToolbar.Enable = 'on';
        app.deviceSettingsToolbar.Enable = 'on';
        app.infoToolbar.Enable = 'on';
      end

    end

    % Window key press function: UIFigure
    function UIFigureWindowKeyPress(app, event)
      
      events = app.m_settings.getEvents();
      key = event.Key;
      switch (key)
        case 'f1'
          app.statusBar = app.statusBar.setText(events(1),3);
        case 'f2'
          app.statusBar.setText(events(2),3);
        case 'f3'
          app.statusBar = app.statusBar.setText(events(3),3);
        case 'f4'
          app.statusBar = app.statusBar.setText(events(4),3);
        case 'f5'
          app.statusBar = app.statusBar.setText(events(5),3);
        case 'f6'
          app.statusBar = app.statusBar.setText(events(6),3);
        case 'f7'
          app.statusBar = app.statusBar.setText(events(7),3);
        case 'f8'
          app.statusBar = app.statusBar.setText(events(8),3);
        case 'f9'
          app.statusBar = app.statusBar.setText(events(9),3);
        case 'f10'
          app.statusBar = app.statusBar.setText(events(10),3);
      end
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'PhysioMon v3.01';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
      app.FileMenu = uimenu(app.UIFigure);
      app.FileMenu.Text = 'File';
      app.StartMenu = uimenu(app.FileMenu);
      app.StartMenu.MenuSelectedFcn = createCallbackFcn(app, @onStart, true);
      app.StartMenu.Tooltip = {'start/stop acquisition'};
      app.StartMenu.Text = 'Start';
      app.SaveMenu = uimenu(app.FileMenu);
      app.SaveMenu.Tooltip = {'save on disk'};
      app.SaveMenu.Text = 'Save';
      app.QuitMenu = uimenu(app.FileMenu);
      app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.QuitMenu.Tooltip = {'exit program'};
      app.QuitMenu.Separator = 'on';
      app.QuitMenu.Text = 'Quit';
      app.EditMenu = uimenu(app.UIFigure);
      app.EditMenu.Text = 'Edit';
      app.onSettingsEdit = uimenu(app.EditMenu);
      app.onSettingsEdit.MenuSelectedFcn = createCallbackFcn(app, @onSettingsEditSelected, true);
      app.onSettingsEdit.Tooltip = {'change general settings'};
      app.onSettingsEdit.Text = 'settings';
      app.onDeviceEdit = uimenu(app.EditMenu);
      app.onDeviceEdit.MenuSelectedFcn = createCallbackFcn(app, @onDeviceEditMenuSelected, true);
      app.onDeviceEdit.Tooltip = {'change device settings'};
      app.onDeviceEdit.Text = 'device';
      app.HelpMenu = uimenu(app.UIFigure);
      app.HelpMenu.Text = 'Help';
      app.aboutphysiomonMenu = uimenu(app.HelpMenu);
      app.aboutphysiomonMenu.MenuSelectedFcn = createCallbackFcn(app, @aboutphysiomonMenuSelected, true);
      app.aboutphysiomonMenu.Text = 'about physiomon';
      app.Toolbar = uitoolbar(app.UIFigure);
      app.startToolbar = uitoggletool(app.Toolbar);
      app.startToolbar.Tooltip = {'start/stop acquisition'};
      app.startToolbar.ClickedCallback = createCallbackFcn(app, @onStart, true);
      app.startToolbar.Icon = 'play_13794082.png';
      app.startToolbar.State = 'on';
      app.saveToolbar = uitoggletool(app.Toolbar);
      app.saveToolbar.Tooltip = {'save on disk'};
      app.generalSettingsToolbar = uipushtool(app.Toolbar);
      app.generalSettingsToolbar.Tooltip = {'change general settings'};
      app.generalSettingsToolbar.ClickedCallback = createCallbackFcn(app, @onSettingsEditSelected, true);
      app.generalSettingsToolbar.Icon = 'adjust_6048035.png';
      app.deviceSettingsToolbar = uipushtool(app.Toolbar);
      app.deviceSettingsToolbar.Tooltip = {'change device settings'};
      app.deviceSettingsToolbar.ClickedCallback = createCallbackFcn(app, @onDeviceEditMenuSelected, true);
      app.deviceSettingsToolbar.Icon = 'repair_5064596.png';
      app.infoToolbar = uipushtool(app.Toolbar);
      app.infoToolbar.Tooltip = {'get info about device'};
      app.LED_3 = uilamp(app.UIFigure);
      app.LED_2 = uilamp(app.UIFigure);
      app.LED_1 = uilamp(app.UIFigure);
      app.statusWindow = uieditfield(app.UIFigure, 'text');
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = physiomon(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end