classdef physiomon_settings < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    physiomonsetttingsUIFigure      matlab.ui.Figure
    CancelButton                    matlab.ui.control.Button
    OKButton                        matlab.ui.control.Button
    settingsPanel                   matlab.ui.container.Panel
    TabGroup                        matlab.ui.container.TabGroup
    channelsTab                     matlab.ui.container.Tab
    selectsignalDropDown            matlab.ui.control.DropDown
    selectsignalDropDownLabel       matlab.ui.control.Label
    displayDropDown                 matlab.ui.control.DropDown
    displayDropDownLabel            matlab.ui.control.Label
    colorEditField                  matlab.ui.control.EditField
    colorEditFieldLabel             matlab.ui.control.Label
    unitsEditField                  matlab.ui.control.EditField
    unitsEditFieldLabel             matlab.ui.control.Label
    nameEditField                   matlab.ui.control.EditField
    nameEditFieldLabel              matlab.ui.control.Label
    typesignalButtonGroup           matlab.ui.container.ButtonGroup
    numericButton                   matlab.ui.control.RadioButton
    waveformButton                  matlab.ui.control.RadioButton
    analogButton                    matlab.ui.control.RadioButton
    dispaysTab                      matlab.ui.container.Tab
    scalePanel                      matlab.ui.container.Panel
    yaxisLabel                      matlab.ui.control.Label
    timesLabel                      matlab.ui.control.Label
    EditField_18                    matlab.ui.control.NumericEditField
    EditField_17                    matlab.ui.control.NumericEditField
    EditField_16                    matlab.ui.control.NumericEditField
    positionoffullwindowPanel       matlab.ui.container.Panel
    widthLabel                      matlab.ui.control.Label
    heightLabel                     matlab.ui.control.Label
    leftLabel                       matlab.ui.control.Label
    topLabel                        matlab.ui.control.Label
    EditField_15                    matlab.ui.control.NumericEditField
    EditField_14                    matlab.ui.control.NumericEditField
    EditField_13                    matlab.ui.control.NumericEditField
    EditField_12                    matlab.ui.control.NumericEditField
    EditField_11                    matlab.ui.control.NumericEditField
    typeButtonGroup                 matlab.ui.container.ButtonGroup
    numericButton_2                 matlab.ui.control.RadioButton
    scopechartButton                matlab.ui.control.RadioButton
    sweepchartButton                matlab.ui.control.RadioButton
    stripchartButton                matlab.ui.control.RadioButton
    eventsTab                       matlab.ui.container.Tab
    EditField_10                    matlab.ui.control.EditField
    Label_10                        matlab.ui.control.Label
    EditField_9                     matlab.ui.control.EditField
    Label_9                         matlab.ui.control.Label
    EditField_8                     matlab.ui.control.EditField
    Label_8                         matlab.ui.control.Label
    EditField_7                     matlab.ui.control.EditField
    Label_7                         matlab.ui.control.Label
    EditField_6                     matlab.ui.control.EditField
    Label_6                         matlab.ui.control.Label
    EditField_5                     matlab.ui.control.EditField
    Label_5                         matlab.ui.control.Label
    EditField_4                     matlab.ui.control.EditField
    Label_4                         matlab.ui.control.Label
    EditField_3                     matlab.ui.control.EditField
    Label_3                         matlab.ui.control.Label
    EditField_2                     matlab.ui.control.EditField
    Label_2                         matlab.ui.control.Label
    EditField                       matlab.ui.control.EditField
    Label                           matlab.ui.control.Label
    numberofchannelsEditField       matlab.ui.control.NumericEditField
    numberofchannelsEditFieldLabel  matlab.ui.control.Label
    numberofdisplaysEditField       matlab.ui.control.NumericEditField
    numberofdisplaysEditFieldLabel  matlab.ui.control.Label
  end

  
  properties (Access = private)
    caller;               % calling app
    dispSettings          % array of structure for display settings
    chanSettings;         % array of structure for channel settings
    evntSettings;         % array of event strings
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, dispSetting, chanSettings, evntSetting, position)
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.physiomonsetttingsUIFigure = uifigure('Visible', 'off');
      app.physiomonsetttingsUIFigure.Name = 'physiomon setttings';
      app.settingsPanel = uipanel(app.physiomonsetttingsUIFigure);
      app.settingsPanel.Title = 'settings';
      app.numberofdisplaysEditFieldLabel = uilabel(app.settingsPanel);
      app.numberofdisplaysEditFieldLabel.Text = 'number of displays';
      app.numberofdisplaysEditField = uieditfield(app.settingsPanel, 'numeric');
      app.numberofchannelsEditFieldLabel = uilabel(app.settingsPanel);
      app.numberofchannelsEditFieldLabel.Text = 'number of channels';
      app.numberofchannelsEditField = uieditfield(app.settingsPanel, 'numeric');
      app.TabGroup = uitabgroup(app.settingsPanel);
      app.channelsTab = uitab(app.TabGroup);
      app.channelsTab.Title = 'channels';
      app.typesignalButtonGroup = uibuttongroup(app.channelsTab);
      app.typesignalButtonGroup.Title = 'type signal';
      app.analogButton = uiradiobutton(app.typesignalButtonGroup);
      app.analogButton.Text = 'analog ';
      app.analogButton.Value = true;
      app.waveformButton = uiradiobutton(app.typesignalButtonGroup);
      app.waveformButton.Text = 'waveform';
      app.numericButton = uiradiobutton(app.typesignalButtonGroup);
      app.numericButton.Text = 'numeric';
      app.nameEditFieldLabel = uilabel(app.channelsTab);
      app.nameEditFieldLabel.Text = 'name';
      app.nameEditField = uieditfield(app.channelsTab, 'text');
      app.unitsEditFieldLabel = uilabel(app.channelsTab);
      app.unitsEditFieldLabel.Text = 'units';
      app.unitsEditField = uieditfield(app.channelsTab, 'text');
      app.colorEditFieldLabel = uilabel(app.channelsTab);
      app.colorEditFieldLabel.Text = 'color';
      app.colorEditField = uieditfield(app.channelsTab, 'text');
      app.displayDropDownLabel = uilabel(app.channelsTab);
      app.displayDropDownLabel.Text = 'display';
      app.displayDropDown = uidropdown(app.channelsTab);
      app.selectsignalDropDownLabel = uilabel(app.channelsTab);
      app.selectsignalDropDownLabel.Text = 'select signal';
      app.selectsignalDropDown = uidropdown(app.channelsTab);
      app.selectsignalDropDown.Items = {'analog 1', 'Option 2', 'Option 3', 'Option 4'};
      app.selectsignalDropDown.Value = 'analog 1';
      app.dispaysTab = uitab(app.TabGroup);
      app.dispaysTab.Title = 'dispays';
      app.typeButtonGroup = uibuttongroup(app.dispaysTab);
      app.typeButtonGroup.Title = 'type ';
      app.stripchartButton = uiradiobutton(app.typeButtonGroup);
      app.stripchartButton.Text = 'strip chart';
      app.stripchartButton.Value = true;
      app.sweepchartButton = uiradiobutton(app.typeButtonGroup);
      app.sweepchartButton.Text = 'sweep chart';
      app.scopechartButton = uiradiobutton(app.typeButtonGroup);
      app.scopechartButton.Text = 'scope chart';
      app.numericButton_2 = uiradiobutton(app.typeButtonGroup);
      app.numericButton_2.Text = 'numeric';
      app.positionoffullwindowPanel = uipanel(app.dispaysTab);
      app.positionoffullwindowPanel.Title = 'position (% of full window)';
      app.EditField_11 = uieditfield(app.positionoffullwindowPanel, 'numeric');
      app.EditField_12 = uieditfield(app.positionoffullwindowPanel, 'numeric');
      app.EditField_13 = uieditfield(app.positionoffullwindowPanel, 'numeric');
      app.EditField_14 = uieditfield(app.positionoffullwindowPanel, 'numeric');
      app.EditField_15 = uieditfield(app.positionoffullwindowPanel, 'numeric');
      app.topLabel = uilabel(app.positionoffullwindowPanel);
      app.topLabel.Text = 'top';
      app.leftLabel = uilabel(app.positionoffullwindowPanel);
      app.leftLabel.Text = 'left';
      app.heightLabel = uilabel(app.positionoffullwindowPanel);
      app.heightLabel.Text = 'height';
      app.widthLabel = uilabel(app.positionoffullwindowPanel);
      app.widthLabel.Text = 'width';
      app.scalePanel = uipanel(app.dispaysTab);
      app.scalePanel.Title = 'scale';
      app.EditField_16 = uieditfield(app.scalePanel, 'numeric');
      app.EditField_17 = uieditfield(app.scalePanel, 'numeric');
      app.EditField_18 = uieditfield(app.scalePanel, 'numeric');
      app.timesLabel = uilabel(app.scalePanel);
      app.timesLabel.Text = 'time (s)';
      app.yaxisLabel = uilabel(app.scalePanel);
      app.yaxisLabel.Text = 'y axis';
      app.eventsTab = uitab(app.TabGroup);
      app.eventsTab.Title = 'events';
      app.Label = uilabel(app.eventsTab);
      app.Label.Text = '1';
      app.EditField = uieditfield(app.eventsTab, 'text');
      app.Label_2 = uilabel(app.eventsTab);
      app.Label_2.Text = '2';
      app.EditField_2 = uieditfield(app.eventsTab, 'text');
      app.Label_3 = uilabel(app.eventsTab);
      app.Label_3.Text = '3';
      app.EditField_3 = uieditfield(app.eventsTab, 'text');
      app.Label_4 = uilabel(app.eventsTab);
      app.Label_4.Text = '4';
      app.EditField_4 = uieditfield(app.eventsTab, 'text');
      app.Label_5 = uilabel(app.eventsTab);
      app.Label_5.Text = '5';
      app.EditField_5 = uieditfield(app.eventsTab, 'text');
      app.Label_6 = uilabel(app.eventsTab);
      app.Label_6.Text = '6';
      app.EditField_6 = uieditfield(app.eventsTab, 'text');
      app.Label_7 = uilabel(app.eventsTab);
      app.Label_7.Text = '7';
      app.EditField_7 = uieditfield(app.eventsTab, 'text');
      app.Label_8 = uilabel(app.eventsTab);
      app.Label_8.Text = '8';
      app.EditField_8 = uieditfield(app.eventsTab, 'text');
      app.Label_9 = uilabel(app.eventsTab);
      app.Label_9.Text = '9';
      app.EditField_9 = uieditfield(app.eventsTab, 'text');
      app.Label_10 = uilabel(app.eventsTab);
      app.Label_10.Text = '10';
      app.EditField_10 = uieditfield(app.eventsTab, 'text');
      app.OKButton = uibutton(app.physiomonsetttingsUIFigure, 'push');
      app.OKButton.Text = 'OK';
      app.CancelButton = uibutton(app.physiomonsetttingsUIFigure, 'push');
      app.CancelButton.Text = 'Cancel';
      % Show the figure after all components are created
      app.physiomonsetttingsUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = physiomon_settings(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.physiomonsetttingsUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.physiomonsetttingsUIFigure)
    end
  end
end