classdef settings_popup < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    physiomonsetttingsUIFigure      matlab.ui.Figure
    CancelButton                    matlab.ui.control.Button
    OKButton                        matlab.ui.control.Button
    settingsPanel                   matlab.ui.container.Panel
    TabGroup                        matlab.ui.container.TabGroup
    channelsTab                     matlab.ui.container.Tab
    chanPrecisionSpinner            matlab.ui.control.Spinner
    precisionSpinnerLabel           matlab.ui.control.Label
    chanColorDropDown               matlab.ui.control.DropDown
    colorDropDownLabel              matlab.ui.control.Label
    channelSpinner                  matlab.ui.control.Spinner
    channelSpinnerLabel             matlab.ui.control.Label
    selectSignalDropDown            matlab.ui.control.DropDown
    selectsignalDropDownLabel       matlab.ui.control.Label
    chanDisplayDropDown             matlab.ui.control.DropDown
    displayDropDownLabel            matlab.ui.control.Label
    chanNameEdit                    matlab.ui.control.EditField
    nameEditFieldLabel              matlab.ui.control.Label
    typeSignalButtonGroup           matlab.ui.container.ButtonGroup
    chanTypeNumeric_rb              matlab.ui.control.RadioButton
    chanTypeWaveform_rb             matlab.ui.control.RadioButton
    chanTypeAnalog_rb               matlab.ui.control.RadioButton
    dispaysTab                      matlab.ui.container.Tab
    displaySpinner                  matlab.ui.control.Spinner
    displaySpinnerLabel             matlab.ui.control.Label
    scalePanel                      matlab.ui.container.Panel
    yaxisLabel                      matlab.ui.control.Label
    timesLabel                      matlab.ui.control.Label
    displayTimeScaleEdit            matlab.ui.control.NumericEditField
    displayYmaxEdit                 matlab.ui.control.NumericEditField
    displayYminEdit                 matlab.ui.control.NumericEditField
    positionoffullwindowPanel       matlab.ui.container.Panel
    widthLabel                      matlab.ui.control.Label
    heightLabel                     matlab.ui.control.Label
    leftLabel                       matlab.ui.control.Label
    topLabel                        matlab.ui.control.Label
    displayWidthEdit                matlab.ui.control.NumericEditField
    unknown                         matlab.ui.control.NumericEditField
    displayHeightEdit               matlab.ui.control.NumericEditField
    displayLeftEdit                 matlab.ui.control.NumericEditField
    displayTopEdit                  matlab.ui.control.NumericEditField
    typeButtonGroup                 matlab.ui.container.ButtonGroup
    numeric_rb                      matlab.ui.control.RadioButton
    scopeChart_rb                   matlab.ui.control.RadioButton
    sweepChart_rb                   matlab.ui.control.RadioButton
    stripChart_rb                   matlab.ui.control.RadioButton
    eventsTab                       matlab.ui.container.Tab
    EditField_10                    matlab.ui.control.EditField
    Label_10                        matlab.ui.control.Label
    EditField_9                     matlab.ui.control.EditField
    Label_9                         matlab.ui.control.Label
    EditField_8                     matlab.ui.control.EditField
    Label_8                         matlab.ui.control.Label
    EditField_7                     matlab.ui.control.EditField
    Label_7                         matlab.ui.control.Label
    EditField_6                     matlab.ui.control.EditField
    Label_6                         matlab.ui.control.Label
    EditField_5                     matlab.ui.control.EditField
    Label_5                         matlab.ui.control.Label
    EditField_4                     matlab.ui.control.EditField
    Label_4                         matlab.ui.control.Label
    EditField_3                     matlab.ui.control.EditField
    Label_3                         matlab.ui.control.Label
    EditField_2                     matlab.ui.control.EditField
    Label_2                         matlab.ui.control.Label
    EditField_1                     matlab.ui.control.EditField
    Label                           matlab.ui.control.Label
    numChannelEdit                  matlab.ui.control.NumericEditField
    numberofchannelsEditFieldLabel  matlab.ui.control.Label
    numDisplayEdit                  matlab.ui.control.NumericEditField
    numberofdisplaysEditFieldLabel  matlab.ui.control.Label
  end

  
  properties (Access = private)

    m_caller;                 % calling app
    m_displayStruct           % array of structure for display settings
    m_channelStruct;          % array of structure for channel settings
    m_evntSettings;           %  array of event strings
    m_analogStruct            % analog channels
    
    define = defines()        % defines 
  end
  
  methods (Access = private)
    
    % function to fill the dropdown box with 1..len, is used a lot of times

    function items = fillDropDownBox(~,len)
      items = cell(len,1);
      for i=1:len, items{i} = num2str(i); end
    end
  
    % given the selected display the edit fields in the gui are filled with the
    % appropriate values

    function fillDisplayInfo(app,dispNr)

      app.displayTopEdit.Value= app.m_displayStruct(dispNr).top;
      app.displayLeftEdit.Value = app.m_displayStruct(dispNr).left;
      app.displayHeightEdit.Value = app.m_displayStruct(dispNr).height;
      app.displayWidthEdit.Value = app.m_displayStruct(dispNr).width;
      app.displayYminEdit.Value = app.m_displayStruct(dispNr).ymin;
      app.displayYmaxEdit.Value = app.m_displayStruct(dispNr).ymax;
      app.displayTimeScaleEdit.Value = app.m_displayStruct(dispNr).timescale;

      app.scopeChart_rb.Value = 0;
      app.sweepChart_rb.Value = 0;
      app.stripChart_rb.Value = 0;
      app.numeric_rb.Value = 0;

      switch (app.m_displayStruct(dispNr).mode)

        case app.define.DISPLAY_MODE_STRIP
          app.stripChart_rb.Value = true;   
        case app.define.DISPLAY_MODE_SWEEP
          app.sweepChart_rb.Value = true;
        case app.define.DISPLAY_MODE_SCOPE
          app.scopeChart_rb.Value = true;
        case app.define.DISPLAY_MODE_NUMERIC
          app.numeric_rb.Value = true;
      end

    end

    % values/ Note, units and colors are currently not used
    
    function fillChannelInfo(app,chanNr)
    
      app.chanNameEdit.Value = app.m_channelStruct(chanNr).name;
      app.selectSignalDropDown.Value = num2str(app.m_channelStruct(chanNr).source + 1);
      app.chanDisplayDropDown.Value = num2str(app.m_channelStruct(chanNr).display);
      app.chanPrecisionSpinner.Value = app.m_channelStruct(chanNr).precision;
      
      switch (app.m_channelStruct(chanNr).color)
        case app.define.COLOR_RED
          app.chanColorDropDown.Value = "red";
        case app.define.COLOR_GREEN
          app.chanColorDropDown.Value = "green";
        case app.define.COLOR_BLUE
          app.chanColorDropDown.Value = "blue";
        case app.define.COLOR_CYAN
          app.chanColorDropDown.Value = "cyan";
         case app.define.COLOR_MAGENTA
          app.chanColorDropDown.Value = "magenta";
        case app.define.COLOR_YELLOW
          app.chanColorDropDown.Value = "yellow";
        case app.define.COLOR_BLACK
          app.chanColorDropDown.Value = "black";
        case app.define.COLOR_WHITE
          app.chanColorDropDown.Value = "white";   
      end
      
      %-jm app.chanColorDropDown.Value = num2str(app.m_channelStruct(chanNr).color+1);

      app.chanTypeAnalog_rb.Value = false;
      app.chanTypeNumeric_rb.Value = false;
      app.chanTypeWaveform_rb.Value = false;

      switch (app.m_channelStruct(chanNr).type)
        case app.define.TYPE_ANALOG_IN
          app.chanTypeAnalog_rb.Value = true;
        case app.define.TYPE_NUMERIC
          app.chanTypeNumeric_rb.Value = true;
        case app.define.TYPE_WAVEFORM
          app.chanTypeWaveform_rb.Value = true;
      end

      % If the display is numeric, precision should be enabled, else disabled

      display = app.m_channelStruct(chanNr).display;
      if app.m_displayStruct(display).mode == app.define.DISPLAY_MODE_NUMERIC
        app.chanPrecisionSpinner.Visible = "on";
        app.precisionSpinnerLabel.Visible = "on";
      else
        app.chanPrecisionSpinner.Visible = "off";
        app.precisionSpinnerLabel.Visible = "off";
      end
    end

  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, channelStruct, displayStruct, eventArray, analogStruct, position)
      
      app.physiomonsetttingsUIFigure.Visible = 'off';

      % be sure always in the window

      appPosition = app.physiomonsetttingsUIFigure.Position;
      appPosition(1) = position(1);
      appPosition(2) = position(2);
      app.physiomonsetttingsUIFigure.Position = appPosition;

      app.m_caller = caller;
      app.m_evntSettings = eventArray;
      app.m_analogStruct = analogStruct;
      app.m_channelStruct = channelStruct;
      app.m_displayStruct = displayStruct;

      % fill the select signal dropdown box with the analog signals

      nrAnalogChan = length(app.m_analogStruct);
      analogNames = cell(nrAnalogChan,1);
      analogItems = cell(nrAnalogChan,1);
      for i = 1:nrAnalogChan
        analogNames{i} = app.m_analogStruct(i).name;
        analogItems{i} = num2str(i);
      end
      app.selectSignalDropDown.Items = analogNames;
      app.selectSignalDropDown.ItemsData = analogItems;

      % do the same for display dropdown box

      items = app.fillDropDownBox(length(app.m_displayStruct));
      app.chanDisplayDropDown.Items = items;
      app.chanDisplayDropDown.ItemsData = items;

      % fill the event fields

      nrEvents = length(app.m_evntSettings);
      for i=1:nrEvents
        field = ['EditField_' num2str(i)];
        app.(field).Value = app.m_evntSettings(i);
      end

      % fill the channel select and display select dropwdown boxes

      app.displaySpinner.Limits = [1 length(app.m_displayStruct)];
      app.channelSpinner.Limits = [1 length(app.m_channelStruct)];

      % now the number of channels and the number of displays

      app.numChannelEdit.Value = length(app.m_channelStruct);
      app.numDisplayEdit.Value = length(app.m_displayStruct);

      % and set the values for the first channel and first display

      app.displaySpinner.Value = 1;
      app.channelSpinner.Value = 1;
      
      fillChannelInfo(app,1);
      fillDisplayInfo(app,1);

      app.physiomonsetttingsUIFigure.Visible = 'on';
    end

    % Button pushed function: OKButton
    function OKButtonPushed(app, event)
      
      % OK button is pressed, copy the structures to the members of the caller and delete
      % the app

      app.m_caller.setSettings(app.m_channelStruct,app.m_displayStruct,app.m_evntSettings);
      app.m_caller.onConfigure();
      
      app.m_caller.statusBar.setText("general settings saved ...",1.5);
      app.delete;

    end

    % Button pushed function: CancelButton
    function CancelButtonPushed(app, event)
      
      % nothing must be stored, just delete the app

      app.delete
    end

    % Value changed function: displaySpinner
    function displaySpinnerValueChanged(app, event)
      
      dispNr = app.displaySpinner.Value;
      fillDisplayInfo(app,dispNr);
      
    end

    % Value changed function: channelSpinner
    function channelSpinnerValueChanged(app, event)
      
      chanNr = app.channelSpinner.Value;
      fillChannelInfo(app,chanNr);
      
    end

    % Value changed function: displayTimeScaleEdit
    function displayTimeScaleEditValueChanged(app, event)

      curDisp = app.displaySpinner.Value;
      app.m_displayStruct(curDisp).timescale = app.displayTimeScaleEdit.Value;

    end

    % Value changed function: displayYmaxEdit
    function displayYmaxEditValueChanged(app, event)
      
      curDisp = app.displaySpinner.Value;
      app.m_displayStruct(curDisp).ymax = app.displayYmaxEdit.Value;    

    end

    % Value changed function: displayYminEdit
    function displayYminEditValueChanged(app, event)
      
      curDisp = app.displaySpinner.Value;
      app.m_displayStruct(curDisp).ymin = app.displayYminEdit.Value;   

    end

    % Value changed function: displayWidthEdit
    function displayWidthEditValueChanged(app, event)
      
      curDisp = app.displaySpinner.Value;
      app.m_displayStruct(curDisp).width = app.displayWidthEdit.Value;  
      
    end

    % Value changed function: displayHeightEdit
    function displayHeightEditValueChanged(app, event)

      curDisp = app.displaySpinner.Value;
      app.m_displayStruct(curDisp).height = app.displayHeightEdit.Value;  

    end

    % Value changed function: displayLeftEdit
    function displayLeftEditValueChanged(app, event)
      
      curDisp = app.displaySpinner.Value;
      app.m_displayStruct(curDisp).left = app.displayLeftEdit.Value;  
      
    end

    % Value changed function: displayTopEdit
    function displayTopEditValueChanged(app, event)

      curDisp = app.displaySpinner.Value;
      app.m_displayStruct(curDisp).top = app.displayTopEdit.Value;  
      
    end

    % Selection changed function: typeButtonGroup
    function typeButtonGroupSelectionChanged(app, event)

      curDisp = app.displaySpinner.Value;

      selectedButton = app.typeButtonGroup.SelectedObject;    
      switch lower(selectedButton.Text)
        case "numeric"
          app.m_displayStruct(curDisp).mode = app.define.DISPLAY_MODE_NUMERIC;
        case "scope chart"
          app.m_displayStruct(curDisp).mode = app.define.DISPLAY_MODE_SCOPE;
        case "sweep chart"
          app.m_displayStruct(curDisp).mode = app.define.DISPLAY_MODE_SWEEP;
        case "strip chart"
          app.m_displayStruct(curDisp).mode = app.define.DISPLAY_MODE_SCOPE;
      end
      
      % and enable/disable precision if current display is channel display

      if (curDisp == str2double(app.chanDisplayDropDown.Value))
        if app.m_displayStruct(curDisp).mode == app.define.DISPLAY_MODE_NUMERIC
          app.chanPrecisionSpinner.Visible = "on";
          app.precisionSpinnerLabel.Visible = "on";
        else
          app.chanPrecisionSpinner.Visible = "off";
          app.precisionSpinnerLabel.Visible = "off";
        end  
      end

    end

    % Value changed function: EditField_1
    function EditField_1ValueChanged(app, event)
      app.m_evntSettings(1) = app.EditField_1.Value;
    end

    % Value changed function: EditField_2
    function EditField_2ValueChanged(app, event)
      app.m_evntSettings(2) = app.EditField_2.Value;
    end

    % Value changed function: EditField_3
    function EditField_3ValueChanged(app, event)
      app.m_evntSettings(3) = app.EditField_3.Value;
    end

    % Value changed function: EditField_4
    function EditField_4ValueChanged(app, event)
      app.m_evntSettings(4) = app.EditField_4.Value;
    end

    % Value changed function: EditField_5
    function EditField_5ValueChanged(app, event)
      app.m_evntSettings(5) = app.EditField_5.Value;
    end

    % Value changed function: EditField_6
    function EditField_6ValueChanged(app, event)
      app.m_evntSettings(6) = app.EditField_6.Value;
    end

    % Value changed function: EditField_7
    function EditField_7ValueChanged(app, event)
      app.m_evntSettings(7) = app.EditField_7.Value; 
    end

    % Value changed function: EditField_8
    function EditField_8ValueChanged(app, event)
      app.m_evntSettings(8) = app.EditField_8.Value;
    end

    % Value changed function: EditField_9
    function EditField_9ValueChanged(app, event)
      app.m_evntSettings(9) = app.EditField_9.Value;
    end

    % Value changed function: EditField_10
    function EditField_10ValueChanged(app, event)
      app.m_evntSetttings(10) = app.EditField_10.Value;
    end

    % Value changed function: chanNameEdit
    function chanNameEditValueChanged(app, event)

      indx = app.channelSpinner.Value; 
      app.m_channelStruct(indx).name = app.chanNameEdit.Value;

    end

    % Value changed function: selectSignalDropDown
    function selectSignalDropDownValueChanged(app, event)
      
      indx = app.channelSpinner.Value; 
      app.m_channelStruct(indx).source = str2double(app.selectSignalDropDown.Value) - 1;

    end

    % Value changed function: chanDisplayDropDown
    function chanDisplayDropDownValueChanged(app, event)
      
      indx = app.channelSpinner.Value;
      display = str2double(app.chanDisplayDropDown.Value);
      app.m_channelStruct(indx).display = display;
      
      % numeric display ?

      if (app.m_displayStruct(display).mode == app.define.DISPLAY_MODE_NUMERIC)
        app.chanPrecisionSpinner.Visible = "on";
        app.precisionSpinnerLabel.Visible = "on";
      else
        app.chanPrecisionSpinner.Visible = "off";
        app.precisionSpinnerLabel.Visible = "off";
      end

    end

    % Value changed function: numChannelEdit
    function numChannelEditValueChanged(app, event)
      
      value = app.numChannelEdit.Value;
      app.channelSpinner.Limits = [1 value];
      
      if (value > length(app.m_channelStruct))
      
        % add some channels

        nrToAdd = value - length(app.m_channelStruct);
        channels = repmat(struct('name','unknown','type',1,'source',0,'display',1),nrToAdd,1);
        app.m_channelStruct = [app.m_channelStruct ; channels];

      else

        % remove some channels

        app.m_channelStruct = app.m_channelStruct(1:value);
        if (value > app.channelSpinner.Value), app.fillChannelInfo(value); end

      end

    end

    % Value changed function: numDisplayEdit
    function numDisplayEditValueChanged(app, event)
      
      value = app.numDisplayEdit.Value;

      % update the display selected and the channel display dropdown box
      
      app.displaySpinner.Limits = [1 value];
  
      items = app.fillDropDownBox(value);
      app.chanDisplayDropDown.Items = items;
      app.chanDisplayDropDown.ItemsData = items;
                
      if (value > length(app.m_displayStruct))
      
        % add some displays

        nrToAdd = value - length(app.m_displayStruct);
        displays = repmat(struct('top',0,'left',0,'width',1,'height',1,'ymin',0,'ymax',1,'timescale',10,'mode',1),nrToAdd,1);
        app.m_displayStruct = [app.m_displayStruct ; displays];

      else

        % remove some channels
        
        app.m_displayStruct = app.m_displayStruct(1:value);
        if (value > app.displaySpinner.Value), app.fillDisplayInfo(value); end
      
      end
    end

    % Value changed function: chanColorDropDown
    function chanColorDropDownValueChanged(app, event)
 
      indx = app.channelSpinner.Value;
      color = app.chanColorDropDown.Value;
      switch (color)
        case 'red'
          app.m_channelStruct(indx).color = app.define.COLOR_RED;
        case 'green'
          app.m_channelStruct(indx).color = app.define.COLOR_GREEN;
        case 'blue'
          app.m_channelStruct(indx).color = app.define.COLOR_BLUE;
        case 'cyan'
          app.m_channelStruct(indx).color = app.define.COLOR_CYAN;
        case 'magenta'
          app.m_channelStruct(indx).color = app.define.COLOR_MAGENTA;
        case 'yellow'
          app.m_channelStruct(indx).color = app.define.COLOR_YELLOW;
        case 'black'
          app.m_channelStruct(indx).color = app.define.COLOR_BLACK;
        case 'white'
          app.m_channelStruct(indx).color = app.define.COLOR_WHITE;
      end

    end

    % Value changed function: chanPrecisionSpinner
    function chanPrecisionSpinnerValueChanged(app, event)
      
      indx = app.channelSpinner.Value;
      app.m_channelStruct(indx).precision = app.chanPrecisionSpinner.Value;
      
    end

    % Selection changed function: typeSignalButtonGroup
    function typeSignalButtonGroupSelectionChanged(app, event)
      
      indx = app.channelSpinner.Value;
      selectedButton = app.typeSignalButtonGroup.SelectedObject;
      switch lower(selectedButton.Text)
        case "numeric"
          app.m_channelStruct(indx).type = app.define.TYPE_NUMERIC;
        case "waveform"
          app.m_channelStruct(indx).type = app.define.TYPE_WAVEFORM;
        case "analog"
          app.m_channelStruct(indx).type = app.define.TYPE_ANALOG;
      end

      % and build list with sources 


    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.physiomonsetttingsUIFigure = uifigure('Visible', 'off');
      app.physiomonsetttingsUIFigure.Name = 'physiomon setttings';
      app.settingsPanel = uipanel(app.physiomonsetttingsUIFigure);
      app.settingsPanel.Title = 'settings';
      app.numberofdisplaysEditFieldLabel = uilabel(app.settingsPanel);
      app.numberofdisplaysEditFieldLabel.Text = 'number of displays';
      app.numDisplayEdit = uieditfield(app.settingsPanel, 'numeric');
      app.numDisplayEdit.ValueChangedFcn = createCallbackFcn(app, @numDisplayEditValueChanged, true);
      app.numberofchannelsEditFieldLabel = uilabel(app.settingsPanel);
      app.numberofchannelsEditFieldLabel.Text = 'number of channels';
      app.numChannelEdit = uieditfield(app.settingsPanel, 'numeric');
      app.numChannelEdit.ValueChangedFcn = createCallbackFcn(app, @numChannelEditValueChanged, true);
      app.TabGroup = uitabgroup(app.settingsPanel);
      app.channelsTab = uitab(app.TabGroup);
      app.channelsTab.Title = 'channels';
      app.typeSignalButtonGroup = uibuttongroup(app.channelsTab);
      app.typeSignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @typeSignalButtonGroupSelectionChanged, true);
      app.typeSignalButtonGroup.Title = 'type signal';
      app.chanTypeAnalog_rb = uiradiobutton(app.typeSignalButtonGroup);
      app.chanTypeAnalog_rb.Text = 'analog ';
      app.chanTypeAnalog_rb.Value = true;
      app.chanTypeWaveform_rb = uiradiobutton(app.typeSignalButtonGroup);
      app.chanTypeWaveform_rb.Enable = 'off';
      app.chanTypeWaveform_rb.Text = 'waveform';
      app.chanTypeNumeric_rb = uiradiobutton(app.typeSignalButtonGroup);
      app.chanTypeNumeric_rb.Enable = 'off';
      app.chanTypeNumeric_rb.Text = 'numeric';
      app.nameEditFieldLabel = uilabel(app.channelsTab);
      app.nameEditFieldLabel.Text = 'name';
      app.chanNameEdit = uieditfield(app.channelsTab, 'text');
      app.chanNameEdit.ValueChangedFcn = createCallbackFcn(app, @chanNameEditValueChanged, true);
      app.displayDropDownLabel = uilabel(app.channelsTab);
      app.displayDropDownLabel.Text = 'display';
      app.chanDisplayDropDown = uidropdown(app.channelsTab);
      app.chanDisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @chanDisplayDropDownValueChanged, true);
      app.selectsignalDropDownLabel = uilabel(app.channelsTab);
      app.selectsignalDropDownLabel.Text = 'select signal';
      app.selectSignalDropDown = uidropdown(app.channelsTab);
      app.selectSignalDropDown.Items = {'analog 1', 'Option 2', 'Option 3', 'Option 4'};
      app.selectSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @selectSignalDropDownValueChanged, true);
      app.selectSignalDropDown.Value = 'analog 1';
      app.channelSpinnerLabel = uilabel(app.channelsTab);
      app.channelSpinnerLabel.Text = 'channel';
      app.channelSpinner = uispinner(app.channelsTab);
      app.channelSpinner.ValueDisplayFormat = '%1d';
      app.channelSpinner.ValueChangedFcn = createCallbackFcn(app, @channelSpinnerValueChanged, true);
      app.colorDropDownLabel = uilabel(app.channelsTab);
      app.colorDropDownLabel.Text = 'color';
      app.chanColorDropDown = uidropdown(app.channelsTab);
      app.chanColorDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
      app.chanColorDropDown.ValueChangedFcn = createCallbackFcn(app, @chanColorDropDownValueChanged, true);
      app.chanColorDropDown.Value = 'red';
      app.precisionSpinnerLabel = uilabel(app.channelsTab);
      app.precisionSpinnerLabel.Visible = 'off';
      app.precisionSpinnerLabel.Text = 'precision';
      app.chanPrecisionSpinner = uispinner(app.channelsTab);
      app.chanPrecisionSpinner.Limits = [0 3];
      app.chanPrecisionSpinner.ValueChangedFcn = createCallbackFcn(app, @chanPrecisionSpinnerValueChanged, true);
      app.chanPrecisionSpinner.Visible = 'off';
      app.dispaysTab = uitab(app.TabGroup);
      app.dispaysTab.Title = 'dispays';
      app.typeButtonGroup = uibuttongroup(app.dispaysTab);
      app.typeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @typeButtonGroupSelectionChanged, true);
      app.typeButtonGroup.Title = 'type ';
      app.stripChart_rb = uiradiobutton(app.typeButtonGroup);
      app.stripChart_rb.Text = 'strip chart';
      app.stripChart_rb.Value = true;
      app.sweepChart_rb = uiradiobutton(app.typeButtonGroup);
      app.sweepChart_rb.Text = 'sweep chart';
      app.scopeChart_rb = uiradiobutton(app.typeButtonGroup);
      app.scopeChart_rb.Text = 'scope chart';
      app.numeric_rb = uiradiobutton(app.typeButtonGroup);
      app.numeric_rb.Text = 'numeric';
      app.positionoffullwindowPanel = uipanel(app.dispaysTab);
      app.positionoffullwindowPanel.Title = 'position (% of full window)';
      app.displayTopEdit = uieditfield(app.positionoffullwindowPanel, 'numeric');
      app.displayTopEdit.Limits = [0 1];
      app.displayTopEdit.ValueDisplayFormat = '%3.2f';
      app.displayTopEdit.ValueChangedFcn = createCallbackFcn(app, @displayTopEditValueChanged, true);
      app.displayLeftEdit = uieditfield(app.positionoffullwindowPanel, 'numeric');
      app.displayLeftEdit.Limits = [0 1];
      app.displayLeftEdit.ValueDisplayFormat = '%3.2f';
      app.displayLeftEdit.ValueChangedFcn = createCallbackFcn(app, @displayLeftEditValueChanged, true);
      app.displayHeightEdit = uieditfield(app.positionoffullwindowPanel, 'numeric');
      app.displayHeightEdit.Limits = [0 1];
      app.displayHeightEdit.ValueDisplayFormat = '%3.2f';
      app.displayHeightEdit.ValueChangedFcn = createCallbackFcn(app, @displayHeightEditValueChanged, true);
      app.unknown = uieditfield(app.positionoffullwindowPanel, 'numeric');
      app.displayWidthEdit = uieditfield(app.positionoffullwindowPanel, 'numeric');
      app.displayWidthEdit.Limits = [0 1];
      app.displayWidthEdit.ValueDisplayFormat = '%3.2f';
      app.displayWidthEdit.ValueChangedFcn = createCallbackFcn(app, @displayWidthEditValueChanged, true);
      app.topLabel = uilabel(app.positionoffullwindowPanel);
      app.topLabel.Text = 'top';
      app.leftLabel = uilabel(app.positionoffullwindowPanel);
      app.leftLabel.Text = 'left';
      app.heightLabel = uilabel(app.positionoffullwindowPanel);
      app.heightLabel.Text = 'height';
      app.widthLabel = uilabel(app.positionoffullwindowPanel);
      app.widthLabel.Text = 'width';
      app.scalePanel = uipanel(app.dispaysTab);
      app.scalePanel.Title = 'scale';
      app.displayYminEdit = uieditfield(app.scalePanel, 'numeric');
      app.displayYminEdit.ValueChangedFcn = createCallbackFcn(app, @displayYminEditValueChanged, true);
      app.displayYmaxEdit = uieditfield(app.scalePanel, 'numeric');
      app.displayYmaxEdit.ValueChangedFcn = createCallbackFcn(app, @displayYmaxEditValueChanged, true);
      app.displayTimeScaleEdit = uieditfield(app.scalePanel, 'numeric');
      app.displayTimeScaleEdit.Limits = [0 1800];
      app.displayTimeScaleEdit.ValueDisplayFormat = '%6.2f';
      app.displayTimeScaleEdit.ValueChangedFcn = createCallbackFcn(app, @displayTimeScaleEditValueChanged, true);
      app.timesLabel = uilabel(app.scalePanel);
      app.timesLabel.Text = 'time (s)';
      app.yaxisLabel = uilabel(app.scalePanel);
      app.yaxisLabel.Text = 'y axis';
      app.displaySpinnerLabel = uilabel(app.dispaysTab);
      app.displaySpinnerLabel.Text = 'display';
      app.displaySpinner = uispinner(app.dispaysTab);
      app.displaySpinner.ValueDisplayFormat = '%1d';
      app.displaySpinner.ValueChangedFcn = createCallbackFcn(app, @displaySpinnerValueChanged, true);
      app.eventsTab = uitab(app.TabGroup);
      app.eventsTab.Title = 'events';
      app.Label = uilabel(app.eventsTab);
      app.Label.Text = '1';
      app.EditField_1 = uieditfield(app.eventsTab, 'text');
      app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
      app.Label_2 = uilabel(app.eventsTab);
      app.Label_2.Text = '2';
      app.EditField_2 = uieditfield(app.eventsTab, 'text');
      app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
      app.Label_3 = uilabel(app.eventsTab);
      app.Label_3.Text = '3';
      app.EditField_3 = uieditfield(app.eventsTab, 'text');
      app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
      app.Label_4 = uilabel(app.eventsTab);
      app.Label_4.Text = '4';
      app.EditField_4 = uieditfield(app.eventsTab, 'text');
      app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
      app.Label_5 = uilabel(app.eventsTab);
      app.Label_5.Text = '5';
      app.EditField_5 = uieditfield(app.eventsTab, 'text');
      app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
      app.Label_6 = uilabel(app.eventsTab);
      app.Label_6.Text = '6';
      app.EditField_6 = uieditfield(app.eventsTab, 'text');
      app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
      app.Label_7 = uilabel(app.eventsTab);
      app.Label_7.Text = '7';
      app.EditField_7 = uieditfield(app.eventsTab, 'text');
      app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
      app.Label_8 = uilabel(app.eventsTab);
      app.Label_8.Text = '8';
      app.EditField_8 = uieditfield(app.eventsTab, 'text');
      app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
      app.Label_9 = uilabel(app.eventsTab);
      app.Label_9.Text = '9';
      app.EditField_9 = uieditfield(app.eventsTab, 'text');
      app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
      app.Label_10 = uilabel(app.eventsTab);
      app.Label_10.Text = '10';
      app.EditField_10 = uieditfield(app.eventsTab, 'text');
      app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
      app.OKButton = uibutton(app.physiomonsetttingsUIFigure, 'push');
      app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
      app.OKButton.Text = 'OK';
      app.CancelButton = uibutton(app.physiomonsetttingsUIFigure, 'push');
      app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
      app.CancelButton.Text = 'Cancel';
      % Show the figure after all components are created
      app.physiomonsetttingsUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = settings_popup(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.physiomonsetttingsUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.physiomonsetttingsUIFigure)
    end
  end
end